classdef appGiaotiepVDK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ControlsDataBytePanel  matlab.ui.container.Panel
        Bit0Button             matlab.ui.control.Button
        Bit1Button             matlab.ui.control.Button
        Bit2Button             matlab.ui.control.Button
        Bit3Button             matlab.ui.control.Button
        Bit4Button             matlab.ui.control.Button
        Bit5Button             matlab.ui.control.Button
        Bit6Button             matlab.ui.control.Button
        Bit7Button             matlab.ui.control.Button
        OpenCOMPortButton      matlab.ui.control.Button
        CloseCOMPortButton     matlab.ui.control.Button
        cngDropDownLabel       matlab.ui.control.Label
        cngDropDown            matlab.ui.control.DropDown
    end


    properties (Access = private)
         COMPort
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CloseCOMPortButton.Visible = 'off';
            app.Bit0Button.Visible = 'off';
            app.Bit1Button.Visible = 'off';
            app.Bit2Button.Visible = 'off';
            app.Bit3Button.Visible = 'off';
            app.Bit4Button.Visible = 'off';
            app.Bit5Button.Visible = 'off';
            app.Bit6Button.Visible = 'off';
            app.Bit7Button.Visible = 'off';
                    
            % Liệt kê các cổng COM có sẵn
            availablePorts = seriallist;
      
            % Đặt danh sách các cổng làm tùy chọn cho dropdown
            app.cngDropDown.Items = availablePorts;
        
            % Chọn mặc định một cổng nếu cần
            app.cngDropDown.Value = availablePorts{1}; % Chọn cổng đầu tiên
        
        end

        % Button pushed function: OpenCOMPortButton
        function OpenCOMPortButtonPushed(app, event)
            %chọn cổng
            selectedPort = app.cngDropDown.Value
            % Tạo đối tượng serialport và cài đặt các thiết lập
            app.COMPort = serial(selectedPort);
            fopen(app.COMPort);

            
            % ẩn các nút cho tới khi các cổng được mở
            app.OpenCOMPortButton.Visible = 'off';
            app.CloseCOMPortButton.Visible = 'on';
         
            app.Bit0Button.Visible = 'on';
            app.Bit1Button.Visible = 'on';
            app.Bit2Button.Visible = 'on';
            app.Bit3Button.Visible = 'on';
            app.Bit4Button.Visible = 'on';
            app.Bit5Button.Visible = 'on';
            app.Bit6Button.Visible = 'on';
            app.Bit7Button.Visible = 'on';


        end

        % Button pushed function: CloseCOMPortButton
        function CloseCOMPortButtonPushed(app, event)
            % Đóng cổng COM nếu nó đã được mở
            if ~isempty(app.COMPort) && isvalid(app.COMPort)
                fclose(app.COMPort);
            end
            app.OpenCOMPortButton.Visible = 'on';
            app.CloseCOMPortButton.Visible = 'off';
            
            app.Bit0Button.Visible = 'off';
            app.Bit1Button.Visible = 'off';
            app.Bit2Button.Visible = 'off';
            app.Bit3Button.Visible = 'off';
            app.Bit4Button.Visible = 'off';
            app.Bit5Button.Visible = 'off';
            app.Bit6Button.Visible = 'off';
            app.Bit7Button.Visible = 'off';
            

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.COMPort) && isvalid(app.COMPort)
                fclose(app.COMPort);
            end
            delete(app.COMPort); % Xóa đối tượng serialport
            delete(app);
        end

        % Button pushed function: Bit0Button
        function Bit0ButtonPushed(app, event)
            fwrite(app.COMPort , [0]);
        end

        % Button pushed function: Bit1Button
        function Bit1ButtonPushed(app, event)
            fwrite(app.COMPort , [1]);
        end

        % Button pushed function: Bit2Button
        function Bit2ButtonPushed(app, event)
            fwrite(app.COMPort , [2]);
        end

        % Button pushed function: Bit3Button
        function Bit3ButtonPushed(app, event)
            fwrite(app.COMPort , [3]);
        end

        % Button pushed function: Bit4Button
        function Bit4ButtonPushed(app, event)
            fwrite(app.COMPort , [4]);
        end

        % Button pushed function: Bit5Button
        function Bit5ButtonPushed(app, event)
            fwrite(app.COMPort , [5]);
        end

        % Button pushed function: Bit6Button
        function Bit6ButtonPushed(app, event)
            fwrite(app.COMPort , [6]);
        end

        % Button pushed function: Bit7Button
        function Bit7ButtonPushed(app, event)
            fwrite(app.COMPort , [7]);
        end

        % Value changed function: cngDropDown
        function cngDropDownValueChanged(app, event)
            value = app.cngDropDown.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ControlsDataBytePanel = uipanel(app.UIFigure);
            app.ControlsDataBytePanel.Title = 'Controls Data Byte';
            app.Bit0Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit0Button.ButtonPushedFcn = createCallbackFcn(app, @Bit0ButtonPushed, true);
            app.Bit0Button.Text = 'Bit 0';
            app.Bit1Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit1Button.ButtonPushedFcn = createCallbackFcn(app, @Bit1ButtonPushed, true);
            app.Bit1Button.Text = 'Bit 1';
            app.Bit2Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit2Button.ButtonPushedFcn = createCallbackFcn(app, @Bit2ButtonPushed, true);
            app.Bit2Button.Text = 'Bit 2';
            app.Bit3Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit3Button.ButtonPushedFcn = createCallbackFcn(app, @Bit3ButtonPushed, true);
            app.Bit3Button.Text = 'Bit 3';
            app.Bit4Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit4Button.ButtonPushedFcn = createCallbackFcn(app, @Bit4ButtonPushed, true);
            app.Bit4Button.Text = 'Bit 4';
            app.Bit5Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit5Button.ButtonPushedFcn = createCallbackFcn(app, @Bit5ButtonPushed, true);
            app.Bit5Button.Text = 'Bit 5';
            app.Bit6Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit6Button.ButtonPushedFcn = createCallbackFcn(app, @Bit6ButtonPushed, true);
            app.Bit6Button.Text = 'Bit 6';
            app.Bit7Button = uibutton(app.ControlsDataBytePanel, 'push');
            app.Bit7Button.ButtonPushedFcn = createCallbackFcn(app, @Bit7ButtonPushed, true);
            app.Bit7Button.Text = 'Bit 7';
            app.OpenCOMPortButton = uibutton(app.ControlsDataBytePanel, 'push');
            app.OpenCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCOMPortButtonPushed, true);
            app.OpenCOMPortButton.Text = 'Open COM Port';
            app.CloseCOMPortButton = uibutton(app.ControlsDataBytePanel, 'push');
            app.CloseCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseCOMPortButtonPushed, true);
            app.CloseCOMPortButton.Text = 'Close COM Port';
            app.cngDropDownLabel = uilabel(app.ControlsDataBytePanel);
            app.cngDropDownLabel.Text = 'cổng';
            app.cngDropDown = uidropdown(app.ControlsDataBytePanel);
            app.cngDropDown.ValueChangedFcn = createCallbackFcn(app, @cngDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = appGiaotiepVDK

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end